classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1              matlab.ui.Figure
        plant5Button         matlab.ui.control.Button
        plant4Button         matlab.ui.control.Button
        plant3Button         matlab.ui.control.Button
        plant2Button         matlab.ui.control.Button
        plant1Button         matlab.ui.control.Button
        plantAllButton       matlab.ui.control.Button
        water5Button         matlab.ui.control.Button
        water4Button         matlab.ui.control.Button
        water3Button         matlab.ui.control.Button
        water2Button         matlab.ui.control.Button
        water1Button         matlab.ui.control.Button
        waterAllButton       matlab.ui.control.Button
        PlantLabel           matlab.ui.control.Label
        AllButton            matlab.ui.control.Button
        WaterLabel           matlab.ui.control.Label
        WaterStopButton      matlab.ui.control.Button
        EStopButton          matlab.ui.control.Button
        GardeningRobotLabel  matlab.ui.control.Label
        plot                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % place your class properties here
        %modelPuma % Where I will put the loaded puma
        %modelUR5 % Where I will put the loaded UR5
        table
        firstAid
        eStop
        seedBowl
        shovel
        modelDobot
        %qrPuma = [0	pi/2  -pi/2	0	0	0];
        %qrUR5 = [pi/4	pi/2  -pi/2	0	0	0];
        qzDobot = [0     0     0     0     0];
        qrDobot = [0 pi/4 pi/4 0 0];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function init(app, varargin)
            % Code to run once GUI is loaded successfully
            ax = gca;

            % Make, save and plot some robots            
            % mdl_puma560
            % app.modelPuma = p560;
            % app.modelPuma.base = transl([0.5,0,0]);
            % plot(app.modelPuma,app.qrPuma,'workspace',[-2 2 -2 2 -0.5 2])            
            % hold on;         
            % 
            % app.modelUR5 = UR5;
            % app.modelUR5.model.base = transl([-0.5,0,0]);
            % app.modelUR5.model.animate(app.qrUR5);
            % drawnow();

            hold on;
            view(3);

            app.table = PlaceObject('myTable5.ply',[0,0,-0.54]);
            app.firstAid = PlaceObject('firstAid.ply',[-0.7,0.3,0.15]);
            app.eStop = PlaceObject('eStop.ply',[-0.7,-0.5,0.05]);

            gardenBed = PlaceObject('gardenBed.ply',[0,0,0]);
            set(gardenBed, 'Vertices', get(gardenBed, 'Vertices') * 0.25 + [0.5, 0.2, 0]);
            
            app.seedBowl = PlaceObject('seed.ply',[0.2,0.1,0]);

            app.modelDobot = DobotMagician;
            app.modelDobot.model.base = transl(0.5, 0.2, 0.1) * trotz(pi/2);
            app.modelDobot.model.animate(app.qrDobot);
            drawnow();

            app.shovel = PlaceObject('shovel.ply');
            shovelVertices = get(app.shovel, 'Vertices');
            transformedVertices = [shovelVertices, ...
                ones(size(shovelVertices, 1), 1)]*transl(0, -0.25, 1)';
            set(app.shovel, 'Vertices', transformedVertices(:, 1:3));

            % Delete the green floor surface (need to count backwards
            % because once it is deleted the array is compressed)
            for i = size(ax.Children,1):-1:1
                if strcmp(ax.Children(i).Type,'surface')
                    delete(ax.Children(i));
                end
            end
            
            % Turn off this load button and enable the move button
            %app.MakeLoadedPumaMoveButton.Enable = true;
            %app.MakeLoadedUR5MoveButton.Enable = true;
            %app.MakeLoadedDobotMoveButton.Enable = true;
            
        end

        % Button pushed function: EStopButton
        function MakeLoadedPumaMoveButtonPushed(app, event)
            qmatrix = jtraj(app.qrPuma,app.qz,20);          
            for robotStepIndex = 1:size(qmatrix,1)

                app.modelPuma.animate(qmatrix(robotStepIndex,:));
                drawnow;
                
            end
        end

        % Button pushed function: WaterStopButton
        function MakeLoadedUR5MoveButtonPushed(app, event)
            qmatrix = jtraj(app.qrUR5,app.qz,20);          
            for robotStepIndex = 1:size(qmatrix,1)

                app.modelUR5.model.animate(qmatrix(robotStepIndex,:));
                drawnow;

            end
        end

        % Button pushed function: plantAllButton
        function MakeDobotMove(app, event)
            currentPos = robot.model.getpos();

            which DobotMagician


            function move2Pos(robot, startPos, endPos, steps, shovelVertices, shovel)
                qMatrix = jtraj(startPos, endPos, steps);
        
                for i = 1:steps
                    robot.model.animate(qMatrix(i,:));
                    tr = robot.model.fkine(qMatrix(i,:));
                    transformedVertices = [shovelVertices, ones(size(shovelVertices, 1),1)]*tr.T';
                    set(shovel, 'Vertices', transformedVertices(:, 1:3));
                    axis equal
                    drawnow()
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'TestRobotPlot_V2';
            app.figure1.Tag = 'figure1';
            app.plot = uiaxes(app.figure1);
            xlabel(app.plot, 'X')
            ylabel(app.plot, 'Y')
            app.plot.Tag = 'axes1';
            app.GardeningRobotLabel = uilabel(app.figure1);
            app.GardeningRobotLabel.Text = 'Gardening Robot';
            app.EStopButton = uibutton(app.figure1, 'push');
            app.EStopButton.ButtonPushedFcn = createCallbackFcn(app, @MakeLoadedPumaMoveButtonPushed, true);
            app.EStopButton.Text = 'E-Stop';
            app.WaterStopButton = uibutton(app.figure1, 'push');
            app.WaterStopButton.ButtonPushedFcn = createCallbackFcn(app, @MakeLoadedUR5MoveButtonPushed, true);
            app.WaterStopButton.Text = 'Water Stop';
            app.WaterLabel = uilabel(app.figure1);
            app.WaterLabel.Text = 'Water';
            app.AllButton = uibutton(app.figure1, 'push');
            app.AllButton.Text = 'All';
            app.PlantLabel = uilabel(app.figure1);
            app.PlantLabel.Text = 'Plant';
            app.waterAllButton = uibutton(app.figure1, 'push');
            app.waterAllButton.Tag = 'WaterAllButton';
            app.waterAllButton.Text = 'All';
            app.water1Button = uibutton(app.figure1, 'push');
            app.water1Button.Text = '1';
            app.water2Button = uibutton(app.figure1, 'push');
            app.water2Button.Text = '2';
            app.water3Button = uibutton(app.figure1, 'push');
            app.water3Button.Text = '3';
            app.water4Button = uibutton(app.figure1, 'push');
            app.water4Button.Text = '4';
            app.water5Button = uibutton(app.figure1, 'push');
            app.water5Button.Text = '5';
            app.plantAllButton = uibutton(app.figure1, 'push');
            app.plantAllButton.ButtonPushedFcn = createCallbackFcn(app, @MakeDobotMove, true);
            app.plantAllButton.Text = 'All';
            app.plant1Button = uibutton(app.figure1, 'push');
            app.plant1Button.Text = '1';
            app.plant2Button = uibutton(app.figure1, 'push');
            app.plant2Button.Text = '2';
            app.plant3Button = uibutton(app.figure1, 'push');
            app.plant3Button.Text = '3';
            app.plant4Button = uibutton(app.figure1, 'push');
            app.plant4Button.Text = '4';
            app.plant5Button = uibutton(app.figure1, 'push');
            app.plant5Button.Text = '5';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)init(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end